classdef JND_app_improved_upgraded < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        JNDFigureUIFigure               matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        PlotthedatasampleTab            matlab.ui.container.Tab
        KSteststatisticEditField        matlab.ui.control.EditField
        KSteststatisticEditFieldLabel   matlab.ui.control.Label
        JNDlevelDropDown                matlab.ui.control.DropDown
        JNDlevelDropDownLabel           matlab.ui.control.Label
        VideoindexEditField             matlab.ui.control.NumericEditField
        VideoindexEditFieldLabel        matlab.ui.control.Label
        CurrentPDFmodelsDropDown        matlab.ui.control.DropDown
        CurrentPDFmodelsDropDownLabel   matlab.ui.control.Label
        FitpdfandcalculateteststatisticButton  matlab.ui.control.Button
        ClearButton                     matlab.ui.control.Button
        PlotButton                      matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        CurveFittingTab                 matlab.ui.container.Tab
        ErrorrateEditField              matlab.ui.control.EditField
        ErrorrateEditFieldLabel         matlab.ui.control.Label
        FitButton                       matlab.ui.control.Button
        ClearButton_2                   matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        ThirdQPEditField                matlab.ui.control.NumericEditField
        ThirdQPEditFieldLabel           matlab.ui.control.Label
        SecondQPEditField               matlab.ui.control.NumericEditField
        SecondQPEditFieldLabel          matlab.ui.control.Label
        FirstQPEditField                matlab.ui.control.NumericEditField
        FirstQPEditFieldLabel           matlab.ui.control.Label
        VideoindexEditField_2           matlab.ui.control.NumericEditField
        VideoindexEditField_2Label      matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        FindQPvalueTab                  matlab.ui.container.Tab
        TextArea                        matlab.ui.control.TextArea
        SearchButton                    matlab.ui.control.Button
        SearchstatusLamp                matlab.ui.control.Lamp
        SearchstatusLampLabel           matlab.ui.control.Label
        RequirementsPanel               matlab.ui.container.Panel
        UsegroundtruthbitratedataButton  matlab.ui.control.StateButton
        EnhancedsearchingmodeButton     matlab.ui.control.StateButton
        ChosenPDFDropDown               matlab.ui.control.DropDown
        ChosenPDFDropDownLabel          matlab.ui.control.Label
        TestLabel                       matlab.ui.control.Label
        MbpsLabel                       matlab.ui.control.Label
        inLabel                         matlab.ui.control.Label
        BitrateconditionEditField       matlab.ui.control.NumericEditField
        BitrateconditionEditFieldLabel  matlab.ui.control.Label
        SURconditionEditField           matlab.ui.control.NumericEditField
        SURconditionEditFieldLabel      matlab.ui.control.Label
        VideoindexEditField_3           matlab.ui.control.NumericEditField
        VideoindexEditField_3Label      matlab.ui.control.Label
        UIAxes3                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            % To get the Nth JND sample values of given video index and transform the
            % data from cell type to the matrix type which is easier for following
            % processing and analysing.
            function [QP_sample_matrix] = my_sample_transform(video_index,level)
                switch level
                    case 1
                        filename_str = 'C:\Yuchen_scripts\JND_project\FYP_codes_to_be_submitted\1280x720_1st.csv';
                    case 2
                        filename_str = 'C:\Yuchen_scripts\JND_project\FYP_codes_to_be_submitted\1280x720_2nd.csv';
                    case 3
                        filename_str = 'C:\Yuchen_scripts\JND_project\FYP_codes_to_be_submitted\1280x720_3rd.csv';
                    otherwise
                        error("Please input a correct level of JND");
                end
                sample_index = ['E' int2str(video_index + 1) ':' 'E' int2str(video_index + 1) ];
                % The second term of the output of 'xlsread' function includes the contents that are
                % not data, and converts it into a cell matrix.
                [~, QP_sample_cell] = xlsread(filename_str,sample_index);
                % Convert the cell matrix to the char matrix.
                QP_sample_char = cell2mat(QP_sample_cell);
                % Convert the char matrix to the double matrix.
                QP_sample_matrix = str2num(QP_sample_char);
            end
            %-----------------------------------------------------------------------------------------------
            % Every time plot new data, clear the previous plot.
            cla(app.UIAxes);
            app.KSteststatisticEditField.Value = ' ';
            % Plot the histogram corresponding to the edit field and drop down inputs.
            video_index = app.VideoindexEditField.Value;
            level = str2double(app.JNDlevelDropDown.Value);
            app.PlotButton.UserData.data_qp = my_sample_transform(video_index,level);
            data_QP = app.PlotButton.UserData.data_qp;
            [N,edges] = histcounts(data_QP);
            bin_center = edges + 0.5;
            bin_center(end) = [];
            n = length(data_QP);
            prob = N / n;
            bar(app.UIAxes,bin_center,prob,'hist');
            app.UIAxes.YLim = [0,max(prob)+0.1];
            app.UIAxes.XLim = [min(data_QP)-5 max(data_QP)+5];
%              app.UIAxes.YLim = [0,0.3];
%             app.UIAxes.XLim = [0,51];
            xlabel(app.UIAxes,'QP value');
            ylabel(app.UIAxes,'Probability density');
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            % Clear the axes.
            cla(app.UIAxes);
            % Clear the edit field.
            app.KSteststatisticEditField.Value = ' ';
        end

        % Button pushed function: 
        % FitpdfandcalculateteststatisticButton
        function FitpdfandcalculateteststatisticButtonPushed(app, event)
            app.CurrentPDFmodelsDropDown.UserData.pdf_model = app.CurrentPDFmodelsDropDown.Value;
            distribution_model = app.CurrentPDFmodelsDropDown.UserData.pdf_model;
            data_QP = app.PlotButton.UserData.data_qp;
            app.CurrentPDFmodelsDropDown.UserData.dist_obj = fitdist(data_QP',distribution_model);
            pd = app.CurrentPDFmodelsDropDown.UserData.dist_obj;
            predicted_curve = pdf(pd,0:51);
            hold(app.UIAxes,'on');
            plot(app.UIAxes,0:51,predicted_curve);
            [h, p, stats]= kstest(data_QP,'CDF',pd);
            test_statistic = stats;
            app.KSteststatisticEditField.Value = num2str(test_statistic);
        end

        % Button pushed function: ClearButton_2
        function ClearButton_2Pushed(app, event)
            % Clear the axes.
            cla(app.UIAxes2);
        end

        % Button pushed function: FitButton
        function FitButtonPushed(app, event)
            % Three input QP values can not all be the same value
            % (two of them are same is acceptable)
            % If input all three same value
            % The result looks bad because the fitting results are too big
            % Thus making the ground truth results relatively small
            % And whole figure looks like two lines (But actually not).
            
            
            % Function for do the linear least square with three bitrate data
            % corresponding to three input QP values, and  return the bitrate
            % fitting results.
            function bitrate_fitting_results = lls_different_QPs(video_index, QP_1, QP_2, QP_3)
                test = load (['C:\Yuchen_scripts\JND_project\FYP_codes_to_be_submitted\Bitrate' ...
                    '\Bitrate_data_for_all_videos.mat']);
                QP = [QP_1;QP_2;QP_3];
                QP_plot_range = 8:47;
                bitrate_array_temp = [log(test.bitrate_data(QP_1 + 1,video_index));...
                    log(test.bitrate_data(QP_2 + 1,video_index));log(test.bitrate_data(QP_3 + 1,video_index))];
                fit_obj = fit(QP,bitrate_array_temp,'poly1');
                p1_coefficient = fit_obj.p1;
                p2_coefficient = fit_obj.p2;
                a_coefficient_lls_temp = exp(p2_coefficient);
                b_coefficient_lls_temp = p1_coefficient;
                bitrate_fitting_results = a_coefficient_lls_temp * exp(b_coefficient_lls_temp * QP_plot_range);
            end
            
            % Function for calculate the average error rate in percentage.
            function error_in_percentage = calculate_error_percentage(bitrate_fitting_results,bitrate_ground_truth)
                error_percentage_temp = abs((bitrate_fitting_results - bitrate_ground_truth)./bitrate_ground_truth);
                error_average = sum(error_percentage_temp) / 40;
                error_in_percentage = error_average;
            end
            
            
            % Clear the current figure.
            cla(app.UIAxes2);
            ground_truth_all = load (['C:\Yuchen_scripts\JND_project\FYP_codes_to_be_submitted\Bitrate' ...
                '\Bitrate_data_for_all_videos.mat']);
            QP_1 = app.FirstQPEditField.Value;
            QP_2 = app.SecondQPEditField.Value;
            QP_3 = app.ThirdQPEditField.Value;
            video_index = app.VideoindexEditField_2.Value;
            QP_range = 8:47;
            bitrate_ground_truth = ground_truth_all.bitrate_data(QP_range+1,video_index);
            plot(app.UIAxes2,QP_range,bitrate_ground_truth,'b*');
            hold(app.UIAxes2,'on');
            bitrate_fitting_results = lls_different_QPs(video_index,QP_1,QP_2,QP_3);
            plot(app.UIAxes2,QP_range,bitrate_fitting_results,'rh');
            legend(app.UIAxes2,'Ground truth bitrate','Fitting bitrate');
            % Calculate the error percentage.
            bitrate_ground_truth = bitrate_ground_truth';
            app.ErrorrateEditField.Value =  num2str(calculate_error_percentage(bitrate_fitting_results,bitrate_ground_truth));
        end

        % Button pushed function: SearchButton
        function SearchButtonPushed(app, event)
            % Clear the Axes.
            cla(app.UIAxes3);
            % Main function
            function [searching_flag, QP_result, searching_level] = Main_function_app_ground_truth(enhanced_searching_flag,mode_flag,N,video_index,SUR_value,Bitrate_condition,pdf_model)
                % Main function
                % First assume we have encoded the original video with three QP values, then
                % we use the bitrate of these three QP values to apply linear least squares
                % to do the curve fitting(assume exponential model). Then we use the
                % resulting function as the relationship between QP value and bitrate for
                % this video.(In this part, I used the points of QP value 10, 15 and 32.)
                % Inputs:
                %   enhanced_searching_flag: state whether the algorithm does further bitrate checking.
                %   mode_flag: Flag for choosing either using ground truth bitrate data(for
                %   testing) or using three points to do lls to get fitting bitrate data.
                %   N: Search iteration, maximum of 3.
                %   video_index: index of the video in VideoSet.
                %   SUR_value: satisfied user ratio requirement.
                %   Bitrate_condition: Bitrate constraint.
                %   pdf_model: probability density function used for modeling JND sample
                %   data.
                % Output:
                %   Returned QP value.
                
                if nargin < 7
                    error('Error: Not enough inputs.');
                elseif nargin > 7
                    error('Error: Too many inputs.');
                end
                if SUR_value >= 1 || SUR_value <= 0
                    error('Error: Input satisfied user ratio must within the range of (0,1).');
                end
                % This matrix stores the all the bitrate data for all 220 videos, since we
                % had 220 videos in total, with QP values ranging from 0-51, the size of
                % the matrix was 52*220.
                test = load (['C:\Yuchen_scripts\JND_project\FYP_codes_to_be_submitted\Bitrate' ...
                    '\Bitrate_data_for_all_videos.mat']);
                
                % Switch mode for changing bitrate data used, 1 stands for using three
                % points to do lls and use fitting bitrate data, 0 stands for using ground
                % truth bitrate data (for testing purpose).
                
                % Using fitting bitrate data.
                if (mode_flag == 0)
                    % In order to do the curve fitting, the matrix should be a column matrix.
                    QP = [10;15;32];
                    % Since my model for the relationship between bitrate and QP value is a
                    % exponential model with function: y = a*exp(b*x). We can take the 'ln' for
                    % both sides of the function and get ln(y) = ln(a) + bx, then use the
                    % lineaar least squares algorithm to do the curve fitting.
                    % Note that during my experiment, I found that the fitting result may vary
                    % when I applied different fitting algorithms and number of points used for
                    % doing the fitting. And fially I deceided to use 3 points to do the linear
                    % least squares, with the exponential model. (Using the relationship mentioned
                    % above.) I chose points with QP values 10, 15 and 32.
                    bitrate_matrix_temp = [log(test.bitrate_data(11,video_index));...
                        log(test.bitrate_data(16,video_index));log(test.bitrate_data(33,video_index))];
                    fit_obj = fit(QP,bitrate_matrix_temp,'poly1');
                    p1_coefficient = fit_obj.p1;
                    p2_coefficient = fit_obj.p2;
                    % Calculate the final coefficients. And use these coefficients to build the
                    % relationship between QP value and bitrate.
                    a_coefficient = exp(p2_coefficient);
                    b_coefficient = p1_coefficient;
                    % Get all the bitrate data for this video based on the curve fitting result.
                    x = 8:47;
                    bitrate_matrix_used_for_comparison = a_coefficient * exp(b_coefficient * x);
                    
                    % Using ground truth bitrate data.
                elseif (mode_flag == 1)
                    bitrate_matrix_used_for_comparison = test.bitrate_data(9:48,video_index);
                end


                QP_result_final = -1;
                
                % Second find the QP value point corresponding to the SUR condition.(In this
                % step we assume that we have already had the pdf of the JND points).
                % We should start from the 1st JND point, if no such QP value is found,
                % then we should carry on with 2nd and 3rd JND points.
                for i = 1:N
                    QP_result_temp = find_QP_based_on_different_pdfs(video_index,SUR_value,i,pdf_model);
                    % Third compare the bitrate of the QP value calculated above to the
                    % requirement.
                    flag_outter = Compare_bitrate(bitrate_matrix_used_for_comparison(QP_result_temp-7),Bitrate_condition);
                    if (flag_outter == -1)
                        continue;
                    else
                        % If the enhanced searching mode is activated, thr algorithm will
                        % further check bitrate condition, meaning that, whether a smaller
                        % QP value stil meets all conditions.
                        if (enhanced_searching_flag == 1)
                            QP_result_temp = QP_result_temp - 1;
                            flag_inner = Compare_bitrate(bitrate_matrix_used_for_comparison(QP_result_temp-7),Bitrate_condition);
                            
                            while (flag_inner == 1)
                                %  Case where the bitrate requirment is too big, the algorithm found the QP of 0.
                                if (QP_result_temp == 8 ) % Thinking about the array indice issue.
                                    break;
                                end
                                QP_result_temp = QP_result_temp - 1;
                                flag_inner = Compare_bitrate(bitrate_matrix_used_for_comparison(QP_result_temp-7),Bitrate_condition);
                            end
                            QP_result_final = QP_result_temp + 1;
                            break;
                        else
                            % Else, the algorithm does not do further checking for bitrate,
                            % and directly return the QP.
                            QP_result_final = QP_result_temp;
                            break;
                        end
                    end
                end
                if (QP_result_final == -1)
                    searching_flag = 0;
                    QP_result = 0;
                    searching_level = 0;
                    % Case where the bitrate requirement was too large, since I only consider QP value
                    % from 7, this should be dealt with.
                elseif(QP_result_final == 9)
                    searching_flag = -1;
                    QP_result = NaN;
                    searching_level = NaN;
                else
                    searching_flag = 1;
                    QP_result = QP_result_final;
                    searching_level = i;
                end
            end
            
            % Find the QP value corresponding to the given video clip under given SUR
            % requirement.
            % Note thta this piece of code is further improved based on my previous
            % work, and this code could use different kinds of PDFs to model the JND
            % sample data.
            function QP_result = find_QP_based_on_different_pdfs (video_index,SUR_value,level,pdf_model)
                % Use the pre-defined functio to read corresponding data into workspace.
                sample_data = my_sample_transform(video_index,level);
                % Fit the data to the given distribution model.
                dist_obj = fitdist(sample_data',pdf_model);
                % The range of QP value.
                QP_value = 0:51;
                % The cdf of the resulting model.
                obj_cdf = cdf(dist_obj,QP_value);
                % SUR curve.
                %                 SUR = 1 - obj_cdf;
                %                 plot(QP_value,SUR,'-o')
                %                 hold on;
                %                 grid on;
                %                 xlabel('QP value');
                %                 ylabel('SUR value');
                %                 switch level
                %                     case 1
                %                         JND_str = '1st';
                %                     case 2
                %                         JND_str = '2nd';
                %                     case 3
                %                         JND_str = '3rd';
                %                 end
                %                 title_str = ['The SUR curve of ' JND_str ' JND for video index' int2str(video_index)];
                %                 title(title_str);
                Inverse_QP_result = icdf(dist_obj,1 - SUR_value);
                % To check whether the output of icdf is an integer (Because for instance
                % if Possion pdf is used, because Possion distribution is discrete
                % distribution, then the output is an integer not an double.
                if rem(Inverse_QP_result,1) == 0
                    % If by chance, the cdf value corresponding to the inverse QP result we
                    % calculate is exactly the (1 - required SUR value), then it is the exactly
                    % result we want (this is relatively unlikely to happen), else, the result QP value should be substracted by 1.
                    if (cdf(dist_obj,Inverse_QP_result) == 1 - SUR_value)
                        QP_result = Inverse_QP_result;
                    else
                        QP_result = Inverse_QP_result - 1;
                    end
                    % Else, the output is of icdf is not an integer, meaning that the PDF model
                    % chosen is a continuous one, then directly use 'floor' function to round
                    % it to zero.
                else
                    QP_result = floor(Inverse_QP_result);
                end
            end
            
            % To get the Nth JND sample values of given video index and transform the
            % data from cell type to the matrix type which is easier for following
            % processing and analysing.
            function [QP_sample_matrix] = my_sample_transform(video_index,level)
                switch level
                    case 1
                        filename_str = 'C:\Yuchen_scripts\JND_project\FYP_codes_to_be_submitted\1280x720_1st.csv';
                    case 2
                        filename_str = 'C:\Yuchen_scripts\JND_project\FYP_codes_to_be_submitted\1280x720_2nd.csv';
                    case 3
                        filename_str = 'C:\Yuchen_scripts\JND_project\FYP_codes_to_be_submitted\1280x720_3rd.csv';
                    otherwise
                        error("Please input a correct level of JND");
                end
                sample_index = ['E' int2str(video_index + 1) ':' 'E' int2str(video_index + 1) ];
                % The second term of the output of 'xlsread' function includes the contents that are
                % not data, and converts it into a cell matrix.
                [num, QP_sample_cell] = xlsread(filename_str,sample_index);
                % Convert the cell matrix to the char matrix.
                QP_sample_char = cell2mat(QP_sample_cell);
                % Convert the char matrix to the double matrix.
                QP_sample_matrix = str2num(QP_sample_char);
            end
            
            
            function flag = Compare_bitrate(bitrate_corrresponding_fitting,bitrate_reality)
                if (bitrate_corrresponding_fitting > bitrate_reality)
                    flag = -1;
                else
                    flag = 1;
                end
            end


            % Read the input from the edit field and drop dowm.
            video_index = app.VideoindexEditField_3.Value;
            SUR_condition = app.SURconditionEditField.Value / 100;
            Bitrate_condition =  app.BitrateconditionEditField.Value * 10^6;
            pdf_model = app.ChosenPDFDropDown.Value;
            if (app.UsegroundtruthbitratedataButton.Value == true)
                mode_flag = 1;
            elseif(app.UsegroundtruthbitratedataButton.Value == false)
                mode_flag = 0;
            end
            if (app.EnhancedsearchingmodeButton.Value == true)
                enhanced_searching_flag = 1;
            elseif(app.EnhancedsearchingmodeButton.Value  == false)
                enhanced_searching_flag = 0;
            end
            
            % Plot the SUR curve on the axes.
            for level = 1:3
                sample_data = my_sample_transform(video_index,level);
                dist_obj = fitdist(sample_data',pdf_model);
                QP_range = 0:51;
                
                obj_cdf = cdf(dist_obj,QP_range);
                % SUR curve.
                SUR = 1 - obj_cdf;
                plot(app.UIAxes3,QP_range,SUR,'-o')
                hold(app.UIAxes3,'on');
                grid(app.UIAxes3,'on');
            end
            % Search the QP value that meets all requirements.
            [searching_flag, QP_result, searching_level] = Main_function_app_ground_truth(enhanced_searching_flag,mode_flag,3,video_index,SUR_condition,Bitrate_condition,pdf_model);
            if searching_flag == 0
                app.SearchstatusLamp.Color = 'Red';
                app.TextArea.Value = ['No QP value such that meets the conditions is found.'];
            elseif (searching_flag == -1)
                app.SearchstatusLamp.Color = 'Yellow';
                app.TextArea.Value = ['Warining: SUR or bitrate requirements are too loose, unrealiable searching result'];
            else
                app.SearchstatusLamp.Color = 'Green';
                switch searching_level
                    case 1
                        num_str = '1st';
                    case 2
                        num_str = '2nd';
                    otherwise
                        num_str = '3rd';
                end
                disp_str = ['The found QP value is in the ' num_str ' JND point pdf.'...
                    char 'The found QP value is ' int2str(QP_result)];
                app.TextArea.Value = disp_str;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.JNDFigureUIFigure = uifigure('Visible', 'off');
            app.JNDFigureUIFigure.Name = 'JND Figure';
            app.TabGroup = uitabgroup(app.JNDFigureUIFigure);
            app.PlotthedatasampleTab = uitab(app.TabGroup);
            app.PlotthedatasampleTab.Title = 'Plot the data sample';
            app.UIAxes = uiaxes(app.PlotthedatasampleTab);
            title(app.UIAxes, 'JND sample data')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.PlotButton = uibutton(app.PlotthedatasampleTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.ClearButton = uibutton(app.PlotthedatasampleTab, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.FitpdfandcalculateteststatisticButton = uibutton(app.PlotthedatasampleTab, 'push');
            app.FitpdfandcalculateteststatisticButton.ButtonPushedFcn = createCallbackFcn(app, @FitpdfandcalculateteststatisticButtonPushed, true);
            app.FitpdfandcalculateteststatisticButton.Text = 'Fit pdf and calculate test statistic';
            app.CurrentPDFmodelsDropDownLabel = uilabel(app.PlotthedatasampleTab);
            app.CurrentPDFmodelsDropDownLabel.Text = 'Current PDF models';
            app.CurrentPDFmodelsDropDown = uidropdown(app.PlotthedatasampleTab);
            app.CurrentPDFmodelsDropDown.Items = {'Normal', 'Poisson', 'Kernel', 'Rayleigh', 'Weibull', 'InverseGaussian', 'Logistic', 'Loglogistic', 'LogNormal', 'Nakagami', 'Rician', 'Gamma', 'BirnbaumSaunders', 'ExtremeValue'};
            app.CurrentPDFmodelsDropDown.Value = 'Normal';
            app.VideoindexEditFieldLabel = uilabel(app.PlotthedatasampleTab);
            app.VideoindexEditFieldLabel.Text = 'Video index';
            app.VideoindexEditField = uieditfield(app.PlotthedatasampleTab, 'numeric');
            app.VideoindexEditField.Limits = [1 220];
            app.VideoindexEditField.Value = 1;
            app.JNDlevelDropDownLabel = uilabel(app.PlotthedatasampleTab);
            app.JNDlevelDropDownLabel.Text = 'JND level';
            app.JNDlevelDropDown = uidropdown(app.PlotthedatasampleTab);
            app.JNDlevelDropDown.Items = {'1', '2', '3'};
            app.JNDlevelDropDown.Value = '1';
            app.KSteststatisticEditFieldLabel = uilabel(app.PlotthedatasampleTab);
            app.KSteststatisticEditFieldLabel.Text = 'K-S test statistic';
            app.KSteststatisticEditField = uieditfield(app.PlotthedatasampleTab, 'text');
            app.KSteststatisticEditField.Editable = 'off';
            app.CurveFittingTab = uitab(app.TabGroup);
            app.CurveFittingTab.Title = 'Curve Fitting';
            app.UIAxes2 = uiaxes(app.CurveFittingTab);
            title(app.UIAxes2, 'Bitrate data (Ground truth vs. fitting results)')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.VideoindexEditField_2Label = uilabel(app.CurveFittingTab);
            app.VideoindexEditField_2Label.Text = 'Video index';
            app.VideoindexEditField_2 = uieditfield(app.CurveFittingTab, 'numeric');
            app.VideoindexEditField_2.Limits = [1 220];
            app.VideoindexEditField_2.Value = 1;
            app.Panel = uipanel(app.CurveFittingTab);
            app.FirstQPEditFieldLabel = uilabel(app.Panel);
            app.FirstQPEditFieldLabel.Text = 'First QP';
            app.FirstQPEditField = uieditfield(app.Panel, 'numeric');
            app.FirstQPEditField.Limits = [8 47];
            app.FirstQPEditField.Value = 8;
            app.SecondQPEditFieldLabel = uilabel(app.Panel);
            app.SecondQPEditFieldLabel.Text = 'Second QP';
            app.SecondQPEditField = uieditfield(app.Panel, 'numeric');
            app.SecondQPEditField.Limits = [8 47];
            app.SecondQPEditField.Value = 8;
            app.ThirdQPEditFieldLabel = uilabel(app.Panel);
            app.ThirdQPEditFieldLabel.Text = 'Third QP';
            app.ThirdQPEditField = uieditfield(app.Panel, 'numeric');
            app.ThirdQPEditField.Limits = [8 47];
            app.ThirdQPEditField.Value = 8;
            app.ClearButton_2 = uibutton(app.CurveFittingTab, 'push');
            app.ClearButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClearButton_2Pushed, true);
            app.ClearButton_2.Text = 'Clear';
            app.FitButton = uibutton(app.CurveFittingTab, 'push');
            app.FitButton.ButtonPushedFcn = createCallbackFcn(app, @FitButtonPushed, true);
            app.FitButton.Text = 'Fit';
            app.ErrorrateEditFieldLabel = uilabel(app.CurveFittingTab);
            app.ErrorrateEditFieldLabel.Text = 'Error rate(%)';
            app.ErrorrateEditField = uieditfield(app.CurveFittingTab, 'text');
            app.FindQPvalueTab = uitab(app.TabGroup);
            app.FindQPvalueTab.Title = 'Find QP value';
            app.UIAxes3 = uiaxes(app.FindQPvalueTab);
            title(app.UIAxes3, 'SUR curve')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.VideoindexEditField_3Label = uilabel(app.FindQPvalueTab);
            app.VideoindexEditField_3Label.Text = 'Video index';
            app.VideoindexEditField_3 = uieditfield(app.FindQPvalueTab, 'numeric');
            app.VideoindexEditField_3.Limits = [1 220];
            app.VideoindexEditField_3.Value = 1;
            app.RequirementsPanel = uipanel(app.FindQPvalueTab);
            app.RequirementsPanel.Title = 'Requirements Panel';
            app.SURconditionEditFieldLabel = uilabel(app.RequirementsPanel);
            app.SURconditionEditFieldLabel.Text = 'SUR condition';
            app.SURconditionEditField = uieditfield(app.RequirementsPanel, 'numeric');
            app.SURconditionEditField.Limits = [0 100];
            app.SURconditionEditField.ValueDisplayFormat = '%5.2f%%';
            app.BitrateconditionEditFieldLabel = uilabel(app.RequirementsPanel);
            app.BitrateconditionEditFieldLabel.Text = 'Bitrate condition';
            app.BitrateconditionEditField = uieditfield(app.RequirementsPanel, 'numeric');
            app.BitrateconditionEditField.Limits = [0.002 1000];
            app.BitrateconditionEditField.Value = 4;
            app.inLabel = uilabel(app.RequirementsPanel);
            app.inLabel.Text = '(in %)';
            app.MbpsLabel = uilabel(app.RequirementsPanel);
            app.MbpsLabel.Text = 'Mbps';
            app.TestLabel = uilabel(app.RequirementsPanel);
            app.TestLabel.Text = 'Test';
            app.ChosenPDFDropDownLabel = uilabel(app.RequirementsPanel);
            app.ChosenPDFDropDownLabel.Text = 'Chosen PDF';
            app.ChosenPDFDropDown = uidropdown(app.RequirementsPanel);
            app.ChosenPDFDropDown.Items = {'Poisson', 'Normal', 'Kernel', 'Rayleigh', 'Weibull', 'InverseGaussian', 'Logistic', 'Loglogistic', 'LogNormal', 'Nakagami', 'Rician', 'Gamma', 'BirnbaumSaunders', 'ExtremeValue'};
            app.ChosenPDFDropDown.Value = 'Poisson';
            app.EnhancedsearchingmodeButton = uibutton(app.RequirementsPanel, 'state');
            app.EnhancedsearchingmodeButton.Text = 'Enhanced searching mode';
            app.UsegroundtruthbitratedataButton = uibutton(app.RequirementsPanel, 'state');
            app.UsegroundtruthbitratedataButton.Text = 'Use ground truth bitrate data';
            app.SearchstatusLampLabel = uilabel(app.FindQPvalueTab);
            app.SearchstatusLampLabel.Text = 'Search status';
            app.SearchstatusLamp = uilamp(app.FindQPvalueTab);
            app.SearchButton = uibutton(app.FindQPvalueTab, 'push');
            app.SearchButton.ButtonPushedFcn = createCallbackFcn(app, @SearchButtonPushed, true);
            app.SearchButton.Text = 'Search';
            app.TextArea = uitextarea(app.FindQPvalueTab);
            app.TextArea.Value = {'Waiting for search...'};
            % Show the figure after all components are created
            app.JNDFigureUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = JND_app_improved_upgraded

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.JNDFigureUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.JNDFigureUIFigure)
        end
    end
end